classdef IntarfazPrueba2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SistemademapeodetuberiasLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        FinalCheckBox                  matlab.ui.control.CheckBox
        SalirButton                    matlab.ui.control.Button
        GraficaFinalButton             matlab.ui.control.Button
        InicioButton                   matlab.ui.control.Button
        Grafica                        matlab.ui.control.UIAxes
        GraficaVivo                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            close all; clc;

            
            device=serialport('COM5',9600);
            configureTerminator(device,"CR/LF");
            w=1;
            mat=zeros(1,1000);
            
            fopen(device);

            r = 2*pi/360;
            VA = [0,0,0];
            VPos = [0,0,0];
            VAn = [0,0,0];

            %hold on;
            while(1)
                %%%%%

                for i = 0:1:200
                    %fopen(s);
                hold on
            
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                
                VRA = fscanf (device,'%s');
                VA = textscan (VRA, '%f', 'Delimiter',',');
                VA = permute (VA{1}, [1,2,3]);
                
                Vx = VA(1)*r;
                Vy = VA(2)*r;
                Vz = VA(3)*r;
            
                VPos(1) = VAn(1) + 1*cos(Vz)*cos(Vy) + (1*cos(Vx)*sin(Vz)+1*sin(Vx)*cos(Vz)*sin(Vy)) + (1*sin(Vx)*sin(Vz)-1*cos(Vx)*cos(Vz)*sin(Vy));
                VPos(2) = VAn(2) + 1*-cos(Vy)*sin(Vz) + (1*cos(Vx)*cos(Vz)-1*sin(Vx)*sin(Vz)*sin(Vy)) + (1*sin(Vx)*cos(Vz)+1*cos(Vx)*sin(Vz)*sin(Vy));
                VPos(3) = VAn(3) + 1* sin(Vy) + 1*-cos(Vy)*sin(Vx) + cos(Vy)*cos(Vx);
            
                plot3(0,0,0,'oblack')
                xlim = [-20 20];
                ylim = [-20 20];
                zlim = [-20 20];
                plot3([VAn(1) VPos(1)],[VAn(2) VPos(2)], [VAn(3) VPos(3)], '-r'); drawnow
                %%xlim([-100 100]);
                %%ylim([-100 100]);
                %%zlim([-100 100]);
            
                delete(instrfind('Port','COM5'));
                VAn = VPos;
                data(w,1:3)=[VPos(1) VPos(2) VPos(3)];
                csvwrite("Cordenadas.csv", data);
                w=w+1;
                pause(0.1)
                if app.FinalCheckBox.Value == 1
                    break
                end
               %fclose(s);
                end
    
                hold off
                plot3([VAn(1) VPos(1)],[VAn(2) VPos(2)], [VAn(3) VPos(3)], '-r'); drawnow

                if app.FinalCheckBox.Value == 1
                    break
                end

                %%%%
                
                delete(instrfind({'Port'},{'COM5'}));
                if app.FinalCheckBox.Value == 1
              
                    break
                end
            end

        end

        % Button pushed function: GraficaFinalButton
        function GraficaFinalButtonPushed(app, event)
            close all; clc;
            M=csvread("Cordenadas.csv")

            i=1;
            j=1;
            k=1;

            while 1
                xg=M(i,1);
                yg=M(j,2);
                zg=M(k,3);
                hold(app.Grafica,"on")
                plot3(app.Grafica,xg,yg,zg,'-ob')
                i=i+1;
                j=j+1;
                k=k+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraficaVivo = uiaxes(app.UIFigure);
            title(app.GraficaVivo, 'Grafica en vivo')
            xlabel(app.GraficaVivo, 'X')
            ylabel(app.GraficaVivo, 'Y')
            zlabel(app.GraficaVivo, 'Z')
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Mapeo Final')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.GraficaFinalButton = uibutton(app.UIFigure, 'push');
            app.GraficaFinalButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaFinalButtonPushed, true);
            app.GraficaFinalButton.Text = 'Grafica Final';
            app.SalirButton = uibutton(app.UIFigure, 'push');
            app.SalirButton.Text = 'Salir';
            app.FinalCheckBox = uicheckbox(app.UIFigure);
            app.FinalCheckBox.Text = 'Final';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'teclogo.png');
            app.SistemademapeodetuberiasLabel = uilabel(app.UIFigure);
            app.SistemademapeodetuberiasLabel.Text = 'Sistema de mapeo de tuberias';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntarfazPrueba2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end